classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        veggiesC    matlab.ui.control.Button
        TryAgain    matlab.ui.control.Button
        ecowarrior  matlab.ui.control.Button
        redcupT     matlab.ui.control.Button
        utensilsT   matlab.ui.control.Button
        flowersC    matlab.ui.control.Button
        teabagC     matlab.ui.control.Button
        metalcanR   matlab.ui.control.Button
        cardboardR  matlab.ui.control.Button
        chipsT      matlab.ui.control.Button
        background  matlab.ui.control.Image
    end

%%NOTE: We worked together on most of the project, including writing and
%%debugging%%

    %%counter refers to number of trash pieces   
%%2 hours
    properties (Access = public)
        
        button_name ='redcupT'
        counter = 8;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
%%maizy was driver, olivia was observer
%%moves last_button according to arrow keys pressed
%%5 hours both in and out of class
            key = event.Key;
            last_button = app.(app.button_name);
            
                if strcmp(key,'leftarrow')
                    last_button.Position(1) = last_button.Position(1) -10;
                end
                if strcmp(key,'rightarrow')
                    last_button.Position(1) = last_button.Position(1) +10;
                end
                if strcmp(key,'uparrow')
                    last_button.Position(2) = last_button.Position(2) +10;
                end
                if strcmp(key,'downarrow')
                    last_button.Position(2) = last_button.Position(2) -10;
%%olivia was driver, maizy was observer
%%if last_button enters correct trash bin, it disappears
%%if last_button enters incorrect trash bin, it is sent back to its
%%starting position
%%20 hours both in and out of class
                    %%TRASH
                    if last_button.Position(2)<=150 && 250 <= last_button.Position(1) && last_button.Position(1) <= 360
                        if app.button_name=="redcupT"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                        if app.button_name=="utensilsT"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                        if app.button_name=="chipsT"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                        if app.button_name=="veggiesC"
                            last_button.Position = [153,287,52,56];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="flowersC"
                            last_button.Position = [257,388,51,76];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="teabagC"
                            last_button.Position = [218,323,63,54];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="metalcanR"
                            last_button.Position = [297,269,50,63];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="cardboardR"
                            last_button.Position = [257,388,51,76];
                            app.TryAgain.Visible = 'on';
                        end
                    end
                    %%COMPOST
                    if last_button.Position(2)<=150 && 0 <= last_button.Position(1) && last_button.Position(1) < 250
                        if app.button_name=="redcupT"
                            last_button.Position = [443 331 59 76];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="utensilsT"
                            last_button.Position = [166,401,52,62];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="chipsT"
                            last_button.Position = [361,308,58,69];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="veggiesC"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                        if app.button_name=="flowersC"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                        if app.button_name=="teabagC"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                        if app.button_name=="metalcanR"
                            last_button.Position = [297,269,50,63];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="cardboardR"
                            last_button.Position = [257,388,51,76];
                            app.TryAgain.Visible = 'on';
                        end
                    end
                    %%RECYCLING
                    if last_button.Position(2)<=150 && 360 < last_button.Position(1) && last_button.Position(1) <= 6112
                        if app.button_name=="redcupT"
                            last_button.Position = [443 331 59 76];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="utensilsT"
                            last_button.Position = [166,401,52,62];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="chipsT"
                            last_button.Position = [361,308,58,69];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="veggiesC"
                            last_button.Position = [153,287,52,56];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="flowersC"
                            last_button.Position = [257,388,51,76];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="teabagC"
                            last_button.Position = [218,323,63,54];
                            app.TryAgain.Visible = 'on';
                        end
                        if app.button_name=="metalcanR"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                        if app.button_name=="cardboardR"
                            last_button.Visible = 'off';
                            app.counter = app.counter - 1;
                        end
                    end
                end
  %%when all trash pieces have disappeared, eco warrior image appears
  %%less than an hour out of class
                if app.counter == 0
                    app.ecowarrior.Visible = 'on';
                end
 %%driver was maizy, observer was olivia
 %%when each button, or trash piece, is pushed, it is able to move
 %%if the try again button appears, it disappears when clicked so player
 %%can continue game
 %%15 hours out of class
        end

        % Button pushed function: utensilsT
        function utensilsTButtonPushed(app, event)
          app.button_name= "utensilsT";
        end

        % Button pushed function: redcupT
        function redcupTButtonPushed(app, event)
          app.button_name= "redcupT" ;
        end

        % Button pushed function: flowersC
        function flowersCButtonPushed(app, event)
          app.button_name= "flowersC";
        end

        % Button pushed function: teabagC
        function teabagCButtonPushed(app, event)
          app.button_name= "teabagC";
        end

        % Button pushed function: metalcanR
        function metalcanRButtonPushed(app, event)
          app.button_name= "metalcanR";
        end

        % Button pushed function: cardboardR
        function cardboardRButtonPushed(app, event)
          app.button_name= "cardboardR";
        end

        % Button pushed function: chipsT
        function chipsTButtonPushed(app, event)
            app.button_name= 'chipsT';
        end

        % Button pushed function: veggiesC
        function veggiesCButtonPushed(app, event)
            app.button_name= 'veggiesC';
        end

        % Button pushed function: TryAgain
        function TryAgainButtonPushed(app, event)
            app.TryAgain.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.background = uiimage(app.UIFigure);
            app.background.ImageSource = fullfile(pathToMLAPP, 'LabelledTrashcan.png');
            app.chipsT = uibutton(app.UIFigure, 'push');
            app.chipsT.ButtonPushedFcn = createCallbackFcn(app, @chipsTButtonPushed, true);
            app.chipsT.Icon = fullfile(pathToMLAPP, 'lays chips.png');
            app.chipsT.Text = '';
            app.cardboardR = uibutton(app.UIFigure, 'push');
            app.cardboardR.ButtonPushedFcn = createCallbackFcn(app, @cardboardRButtonPushed, true);
            app.cardboardR.Icon = fullfile(pathToMLAPP, 'cardboard.png');
            app.cardboardR.Text = '';
            app.metalcanR = uibutton(app.UIFigure, 'push');
            app.metalcanR.ButtonPushedFcn = createCallbackFcn(app, @metalcanRButtonPushed, true);
            app.metalcanR.Icon = fullfile(pathToMLAPP, 'metal can.png');
            app.metalcanR.Text = '';
            app.teabagC = uibutton(app.UIFigure, 'push');
            app.teabagC.ButtonPushedFcn = createCallbackFcn(app, @teabagCButtonPushed, true);
            app.teabagC.Icon = fullfile(pathToMLAPP, 'tea bag.png');
            app.teabagC.Text = '';
            app.flowersC = uibutton(app.UIFigure, 'push');
            app.flowersC.ButtonPushedFcn = createCallbackFcn(app, @flowersCButtonPushed, true);
            app.flowersC.Icon = fullfile(pathToMLAPP, 'dead flowers.png');
            app.flowersC.Text = '';
            app.utensilsT = uibutton(app.UIFigure, 'push');
            app.utensilsT.ButtonPushedFcn = createCallbackFcn(app, @utensilsTButtonPushed, true);
            app.utensilsT.Icon = fullfile(pathToMLAPP, 'Utensils.png');
            app.utensilsT.Text = '';
            app.redcupT = uibutton(app.UIFigure, 'push');
            app.redcupT.ButtonPushedFcn = createCallbackFcn(app, @redcupTButtonPushed, true);
            app.redcupT.Icon = fullfile(pathToMLAPP, 'plastic_cup.png');
            app.redcupT.Text = '';
            app.ecowarrior = uibutton(app.UIFigure, 'push');
            app.ecowarrior.Visible = 'off';
            app.ecowarrior.Text = '';
            app.ecowarrior.Icon = fullfile(pathToMLAPP, 'eco_warrior.png');
            app.TryAgain = uibutton(app.UIFigure, 'push');
            app.TryAgain.ButtonPushedFcn = createCallbackFcn(app, @TryAgainButtonPushed, true);
            app.TryAgain.Visible = 'off';
            app.TryAgain.Text = {'Try Again! '; 'Click here to resume.'};
            app.veggiesC = uibutton(app.UIFigure, 'push');
            app.veggiesC.ButtonPushedFcn = createCallbackFcn(app, @veggiesCButtonPushed, true);
            app.veggiesC.Icon = fullfile(pathToMLAPP, 'veggies.png');
            app.veggiesC.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end